classdef gui_assignment_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure  matlab.ui.Figure
        DatasetButtonGroup              matlab.ui.container.ButtonGroup
        Patient1Button                  matlab.ui.control.RadioButton
        Patient2Button                  matlab.ui.control.RadioButton
        TrainMultilayerNetworkPanel     matlab.ui.container.Panel
        GoalDropDownLabel               matlab.ui.control.Label
        GoalDropDown                    matlab.ui.control.DropDown
        TrainingDropDownLabel           matlab.ui.control.Label
        TrainingDropDown                matlab.ui.control.DropDown
        ClassBalanceDropDownLabel       matlab.ui.control.Label
        ClassBalanceDropDown            matlab.ui.control.DropDown
        NeuronsperLayerDropDownLabel    matlab.ui.control.Label
        NeuronsperLayerDropDown         matlab.ui.control.DropDown
        TrainMultilayerButton           matlab.ui.control.Button
        SpecializationErrorDropDownLabel  matlab.ui.control.Label
        SpecializationErrorDropDown     matlab.ui.control.DropDown
        NumberOfFeaturesDropDownLabel   matlab.ui.control.Label
        NumberOfFeaturesDropDown        matlab.ui.control.DropDown
        NumberOfFeaturesDropDownLabel_2  matlab.ui.control.Label
        NumberOfFeaturesDropDown_2      matlab.ui.control.DropDown
        TestTrainedNeuralNetworkPanel   matlab.ui.container.Panel
        TestButton                      matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        ClassificationTypeDropDown_2Label  matlab.ui.control.Label
        ClassificationTypeDropDown_2    matlab.ui.control.DropDown
        ResultsPanel                    matlab.ui.container.Panel
        SensitivityLabel                matlab.ui.control.Label
        SpecificityLabel                matlab.ui.control.Label
        LabelSpecificity                matlab.ui.control.Label
        LabelSensitivity                matlab.ui.control.Label
        PredictionLabel                 matlab.ui.control.Label
        DetectionLabel                  matlab.ui.control.Label
        SensitivityLabel_2              matlab.ui.control.Label
        SpecificityLabel_2              matlab.ui.control.Label
        LabelSpecificity_2              matlab.ui.control.Label
        LabelSensitivity_2              matlab.ui.control.Label
        TruePositivesLabel              matlab.ui.control.Label
        LabelTP                         matlab.ui.control.Label
        TrueNegativeLabel               matlab.ui.control.Label
        LabelTN                         matlab.ui.control.Label
        FalsePositivesLabel             matlab.ui.control.Label
        LabelFP                         matlab.ui.control.Label
        FalseNegativesLabel             matlab.ui.control.Label
        LabelFN                         matlab.ui.control.Label
        TruePositivesLabel_2            matlab.ui.control.Label
        LabelTP2                        matlab.ui.control.Label
        TrueNegativeLabel_2             matlab.ui.control.Label
        LabelTN2                        matlab.ui.control.Label
        FalsePositivesLabel_2           matlab.ui.control.Label
        LabelFP2                        matlab.ui.control.Label
        FalseNegativesLabel_2           matlab.ui.control.Label
        LabelFN2                        matlab.ui.control.Label
        AccuracyLabel                   matlab.ui.control.Label
        AccuracyDetection               matlab.ui.control.Label
        AccuracyLabel_2                 matlab.ui.control.Label
        AccuracyPrediction              matlab.ui.control.Label
        DatasetBuildLampLabel           matlab.ui.control.Label
        DatasetBuildLamp                matlab.ui.control.Lamp
        NetworkTrainedLampLabel         matlab.ui.control.Label
        NetworkTrainedLamp              matlab.ui.control.Lamp
        NetworkSavedLampLabel           matlab.ui.control.Label
        NetworkSavedLamp                matlab.ui.control.Lamp
        NetworkSavedLampLabel_2         matlab.ui.control.Label
        NetworkTestedLamp               matlab.ui.control.Lamp
        TrainRecurrentNetworkPanel      matlab.ui.container.Panel
        GoalDropDown_2Label             matlab.ui.control.Label
        GoalDropDown_Rec                matlab.ui.control.DropDown
        TrainingDropDown_2Label         matlab.ui.control.Label
        TrainingDropDown_Rec            matlab.ui.control.DropDown
        NeuronsperLayersDropDown_2Label  matlab.ui.control.Label
        NeuronsperLayersDropDown_Rec    matlab.ui.control.DropDown
        TrainRecButton                  matlab.ui.control.Button
        SpecializationErrorDropDown_2Label  matlab.ui.control.Label
        SpecializationErrorDropDown_Rec  matlab.ui.control.DropDown
        NumberOfFeaturesDropDownLabel_Rec  matlab.ui.control.Label
        NumberOfFeaturesDropDown_Rec    matlab.ui.control.DropDown
        ClassificationTypeDropDownLabel_Rec  matlab.ui.control.Label
        ClassificationTypeDropDown_Rec  matlab.ui.control.DropDown
        NoClassBalancingTimeSeriesLabel  matlab.ui.control.Label
        TrainDeepLearningPanel          matlab.ui.container.Panel
        NetworkTypeDropDown_3Label      matlab.ui.control.Label
        DeepNetworkType                 matlab.ui.control.DropDown
        TrainButton                     matlab.ui.control.Button
        Clusteringwith2and3featuresencodedPanel  matlab.ui.container.Panel
        Plot2dButton                    matlab.ui.control.Button
        Plot3dButton                    matlab.ui.control.Button
        ClustersLabel                   matlab.ui.control.Label
        ClustersDropDown                matlab.ui.control.DropDown
    end

    
    methods (Access = private)
        
        function update_trained_nets(app)
            trained_nets = dir('trained_nets'); % Folder structure
            
            names = strings; % Files name - Empty character vector
            for i = 3:length(trained_nets)
                name = trained_nets(i,1).name;
                names(i - 2,1) = name;
            end
            
            app.DropDown.Items = names;
        end
        
        function sem_all_black(app)
            app.DatasetBuildLamp.Color = 'black';
            app.NetworkTrainedLamp.Color = 'black';
            app.NetworkSavedLamp.Color = 'black';
            app.NetworkTestedLamp.Color = 'black';
        end
        
        function sem_all_red(app)
            app.DatasetBuildLamp.Color = 'red';
            app.NetworkTrainedLamp.Color = 'red';
            app.NetworkSavedLamp.Color = 'red';
            app.NetworkTestedLamp.Color = 'red';
        end
        
        function print_results(app, prediction, detection)
            % Prediction Performance
            app.LabelSensitivity.Text = num2str(prediction.sensitivity);
            app.LabelSpecificity.Text = num2str(prediction.specificity);
            app.LabelTP.Text = num2str(prediction.TP);
            app.LabelTN.Text = num2str(prediction.TN);
            app.LabelFP.Text = num2str(prediction.FP);
            app.LabelFN.Text = num2str(prediction.FN);
            app.AccuracyPrediction.Text = num2str(prediction.accuracy);
            
            % Detection Performance
            app.LabelSensitivity_2.Text = num2str(detection.sensitivity);
            app.LabelSpecificity_2.Text = num2str(detection.specificity);
            app.LabelTP2.Text = num2str(detection.TP);
            app.LabelTN2.Text = num2str(detection.TN);
            app.LabelFP2.Text = num2str(detection.FP);
            app.LabelFN2.Text = num2str(detection.FN);
            app.AccuracyDetection.Text = num2str(detection.accuracy);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            update_trained_nets(app)
        end

        % Button pushed function: TrainMultilayerButton
        function TrainMultilayerButtonPushed(app, event)
            disp('Train Multilayer')
            sem_all_black(app);
            
            datasetValue = app.DatasetButtonGroup.SelectedObject.Text;
            
            dataset_idx = 0;
            type = 'Multilayer';
            goal = app.GoalDropDown.Value;
            specialization = app.SpecializationErrorDropDown.Value;
            trainRatio = str2double(app.TrainingDropDown.Value);
            balance = str2double(app.ClassBalanceDropDown.Value);
            num_neurons = str2double(app.NeuronsperLayerDropDown.Value);
            num_features = str2double(app.NumberOfFeaturesDropDown.Value);
            
            clf_type = app.ClassificationTypeDropDown_2.Value;      
            
            if strcmp(datasetValue,'Patient 1')
                dataset_idx = 1;
            elseif strcmp(datasetValue,'Patient 2')
                dataset_idx = 2;
            end
            
            sem_all_red(app);
            
            
            % Build dataset
            [P_train,T_train, P_test, T_test] = build_dataset(dataset_idx, ...
                num_features, trainRatio, balance);
            app.DatasetBuildLamp.Color = 'green';
            
            % Train network
            trained_net = train_network(P_train, T_train, type, num_neurons, ...
                goal,specialization);
            app.NetworkTrainedLamp.Color = 'green';
            
            % Save network
            save_trained_net(P_test,T_test, trained_net, dataset_idx, type, ...
                goal, specialization, trainRatio, balance,num_neurons, num_features);
            app.NetworkSavedLamp.Color = 'green';
            
            % Test Network
            [prediction, detection] = test_network(clf_type, trained_net, P_test, T_test);  
            app.NetworkTestedLamp.Color = 'green';
            
            print_results(app, prediction, detection);
            update_trained_nets(app);
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            disp('Test');
            sem_all_black(app);
           
            clf_type = app.ClassificationTypeDropDown_2.Value;
            trained_net_name = app.DropDown.Value;
            path = strcat('trained_nets/',trained_net_name);
            load(path);
            
            % Test Network
            if strcmp(trained_net_name(4:7),'LSTM') || strcmp(trained_net_name(4:6),'CNN')
                [prediction, detection] = test_deep_network(clf_type, trained_net, P_test, T_test);
            else
                [prediction, detection] = test_network(clf_type, trained_net, P_test, T_test);  
            end
            app.NetworkTestedLamp.Color = 'green';
            
            print_results(app, prediction, detection);
        end

        % Callback function
        function DropDownValueChanged(app, event)
          
        end

        % Button pushed function: TrainRecButton
        function TrainRecButtonPushed(app, event)
            disp('Train Multilayer')
            sem_all_black(app);
            
            sem_all_red(app);
            
            datasetValue = app.DatasetButtonGroup.SelectedObject.Text;
            
            dataset_idx = 0;
            type = 'Multilayer with Delays';
            goal = app.GoalDropDown_Rec.Value;
            specialization = app.SpecializationErrorDropDown_Rec.Value;
            trainRatio = str2double(app.TrainingDropDown_Rec.Value);
            balance = 0; % No balance - Time-series (delays)
            num_neurons = str2double(app.NeuronsperLayersDropDown_Rec.Value);
            num_features = str2double(app.NumberOfFeaturesDropDown_Rec.Value);
            
            clf_type = app.ClassificationTypeDropDown_Rec.Value;      
            
            if strcmp(datasetValue,'Patient 1')
                dataset_idx = 1;
            elseif strcmp(datasetValue,'Patient 2')
                dataset_idx = 2;
            end
            
            sem_all_red(app);
            
            % Build dataset
            [P_train,T_train, P_test, T_test] = build_dataset(dataset_idx, ...
                num_features, trainRatio, balance);
            app.DatasetBuildLamp.Color = 'green';
            
            % Train network
            trained_net = train_network(P_train, T_train, type, num_neurons, ...
                goal,specialization);
            app.NetworkTrainedLamp.Color = 'green';
            
            % Save network
            save_trained_net(P_test,T_test, trained_net, dataset_idx, type, ...
                goal, specialization, trainRatio, balance,num_neurons, num_features);
            app.NetworkSavedLamp.Color = 'green';
            
            % Test Network
            [prediction, detection] = test_network(clf_type, trained_net, P_test, T_test);  
            app.NetworkTestedLamp.Color = 'green';
            
            print_results(app, prediction, detection);
            update_trained_nets(app);
        end

        % Button pushed function: Plot2dButton
        function Plot2dButtonPushed(app, event)
            disp('Clustering - Plot 2d')
            sem_all_black(app);
            
            num_clusters = str2double(app.ClustersDropDown.Value);
            
            plot_cluster(num_clusters,2);
        end

        % Button pushed function: Plot3dButton
        function Plot3dButtonPushed(app, event)
            disp('Clustering - Plot 3d')
            sem_all_black(app);
            
            num_clusters = str2double(app.ClustersDropDown.Value);
            
            plot_cluster(num_clusters,3);
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            disp('Train - Deep Learning')
            sem_all_black(app);
            sem_all_red(app);
            
            type = app.DeepNetworkType.Value;
            datasetValue = app.DatasetButtonGroup.SelectedObject.Text;
            dataset_idx = 0;
            
            if strcmp(datasetValue,'Patient 1')
                dataset_idx = 1;
            elseif strcmp(datasetValue,'Patient 2')
                dataset_idx = 2;
            end
            
            if strcmp(type,'LSTM')
                clf_type = 'Single';
                num_features = 29;
                trainRatio = 0.7;
                specialization = 'noSpec';
                balance = 0; % No balance - Time-series (delays)
                goal = 'noGoal';
                num_neurons = 0;
            end
                    
            % Build dataset
            [P_train,T_train, P_test, T_test] = build_dataset(dataset_idx, ...
                num_features, trainRatio, balance);
            app.DatasetBuildLamp.Color = 'green';
            
            % Train network
            trained_net = train_network(P_train, T_train, type, num_neurons, ...
                goal,specialization);
            app.NetworkTrainedLamp.Color = 'green';
            
            % Save network
            save_trained_net(P_test,T_test, trained_net, dataset_idx, type, ...
                goal, specialization, trainRatio, balance,num_neurons, num_features);
            app.NetworkSavedLamp.Color = 'green';
            
            % Test Network
            [prediction, detection] = test_deep_network(clf_type,trained_net,P_test,T_test); 
            app.NetworkTestedLamp.Color = 'green';
            
            print_results(app, prediction, detection);
            update_trained_nets(app);        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure = uifigure('Visible', 'off');
            app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure.Name = 'Assigment #3 - Prediction and Detection of Epileptic Seizures';
            app.DatasetButtonGroup = uibuttongroup(app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.DatasetButtonGroup.Title = 'Dataset';
            app.Patient1Button = uiradiobutton(app.DatasetButtonGroup);
            app.Patient1Button.Text = 'Patient 1';
            app.Patient1Button.Value = true;
            app.Patient2Button = uiradiobutton(app.DatasetButtonGroup);
            app.Patient2Button.Text = 'Patient 2';
            app.TrainMultilayerNetworkPanel = uipanel(app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.TrainMultilayerNetworkPanel.Title = 'Train Multilayer Network';
            app.GoalDropDownLabel = uilabel(app.TrainMultilayerNetworkPanel);
            app.GoalDropDownLabel.Text = 'Goal';
            app.GoalDropDown = uidropdown(app.TrainMultilayerNetworkPanel);
            app.GoalDropDown.Items = {'Prediction', 'Detection'};
            app.GoalDropDown.Value = 'Prediction';
            app.TrainingDropDownLabel = uilabel(app.TrainMultilayerNetworkPanel);
            app.TrainingDropDownLabel.Text = 'Training %';
            app.TrainingDropDown = uidropdown(app.TrainMultilayerNetworkPanel);
            app.TrainingDropDown.Items = {'0.8', '0.7'};
            app.TrainingDropDown.Value = '0.7';
            app.ClassBalanceDropDownLabel = uilabel(app.TrainMultilayerNetworkPanel);
            app.ClassBalanceDropDownLabel.Text = 'Class Balance';
            app.ClassBalanceDropDown = uidropdown(app.TrainMultilayerNetworkPanel);
            app.ClassBalanceDropDown.Items = {'1', '10'};
            app.ClassBalanceDropDown.Value = '1';
            app.NeuronsperLayerDropDownLabel = uilabel(app.TrainMultilayerNetworkPanel);
            app.NeuronsperLayerDropDownLabel.Text = 'Neurons per Layer';
            app.NeuronsperLayerDropDown = uidropdown(app.TrainMultilayerNetworkPanel);
            app.NeuronsperLayerDropDown.Items = {'10', '15', '20', '40', '120'};
            app.NeuronsperLayerDropDown.Value = '20';
            app.TrainMultilayerButton = uibutton(app.TrainMultilayerNetworkPanel, 'push');
            app.TrainMultilayerButton.ButtonPushedFcn = createCallbackFcn(app, @TrainMultilayerButtonPushed, true);
            app.TrainMultilayerButton.Text = 'Train';
            app.SpecializationErrorDropDownLabel = uilabel(app.TrainMultilayerNetworkPanel);
            app.SpecializationErrorDropDownLabel.Text = 'Specialization (Error)';
            app.SpecializationErrorDropDown = uidropdown(app.TrainMultilayerNetworkPanel);
            app.SpecializationErrorDropDown.Items = {'High', 'Medium', 'None'};
            app.SpecializationErrorDropDown.Value = 'High';
            app.NumberOfFeaturesDropDownLabel = uilabel(app.TrainMultilayerNetworkPanel);
            app.NumberOfFeaturesDropDownLabel.Text = 'Number of Features';
            app.NumberOfFeaturesDropDown = uidropdown(app.TrainMultilayerNetworkPanel);
            app.NumberOfFeaturesDropDown.Items = {'29', '25', '20', '10'};
            app.NumberOfFeaturesDropDown.Value = '29';
            app.NumberOfFeaturesDropDownLabel_2 = uilabel(app.TrainMultilayerNetworkPanel);
            app.NumberOfFeaturesDropDownLabel_2.Text = 'Classification Type';
            app.NumberOfFeaturesDropDown_2 = uidropdown(app.TrainMultilayerNetworkPanel);
            app.NumberOfFeaturesDropDown_2.Items = {'Single', 'Group'};
            app.NumberOfFeaturesDropDown_2.Value = 'Single';
            app.TestTrainedNeuralNetworkPanel = uipanel(app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.TestTrainedNeuralNetworkPanel.Title = 'Test Trained Neural Network';
            app.TestButton = uibutton(app.TestTrainedNeuralNetworkPanel, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.DropDown = uidropdown(app.TestTrainedNeuralNetworkPanel);
            app.DropDown.Items = {'d1_Multilayer_Prediction_High_80_ClassBalanced_20_29.mat', 'Option 2', 'Option 3', 'Option 4'};
            app.DropDown.Value = 'd1_Multilayer_Prediction_High_80_ClassBalanced_20_29.mat';
            app.ClassificationTypeDropDown_2Label = uilabel(app.TestTrainedNeuralNetworkPanel);
            app.ClassificationTypeDropDown_2Label.Text = 'Classification Type';
            app.ClassificationTypeDropDown_2 = uidropdown(app.TestTrainedNeuralNetworkPanel);
            app.ClassificationTypeDropDown_2.Items = {'Single', 'Group'};
            app.ClassificationTypeDropDown_2.Value = 'Single';
            app.ResultsPanel = uipanel(app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.ResultsPanel.Title = 'Results';
            app.SensitivityLabel = uilabel(app.ResultsPanel);
            app.SensitivityLabel.Text = 'Sensitivity:';
            app.SpecificityLabel = uilabel(app.ResultsPanel);
            app.SpecificityLabel.Text = 'Specificity:';
            app.LabelSpecificity = uilabel(app.ResultsPanel);
            app.LabelSpecificity.Text = '0';
            app.LabelSensitivity = uilabel(app.ResultsPanel);
            app.LabelSensitivity.Text = '0';
            app.PredictionLabel = uilabel(app.ResultsPanel);
            app.PredictionLabel.Text = 'Prediction';
            app.DetectionLabel = uilabel(app.ResultsPanel);
            app.DetectionLabel.Text = 'Detection';
            app.SensitivityLabel_2 = uilabel(app.ResultsPanel);
            app.SensitivityLabel_2.Text = 'Sensitivity:';
            app.SpecificityLabel_2 = uilabel(app.ResultsPanel);
            app.SpecificityLabel_2.Text = 'Specificity:';
            app.LabelSpecificity_2 = uilabel(app.ResultsPanel);
            app.LabelSpecificity_2.Text = '0';
            app.LabelSensitivity_2 = uilabel(app.ResultsPanel);
            app.LabelSensitivity_2.Text = '0';
            app.TruePositivesLabel = uilabel(app.ResultsPanel);
            app.TruePositivesLabel.Text = 'True Positives:';
            app.LabelTP = uilabel(app.ResultsPanel);
            app.LabelTP.Text = '0';
            app.TrueNegativeLabel = uilabel(app.ResultsPanel);
            app.TrueNegativeLabel.Text = 'True Negative:';
            app.LabelTN = uilabel(app.ResultsPanel);
            app.LabelTN.Text = '0';
            app.FalsePositivesLabel = uilabel(app.ResultsPanel);
            app.FalsePositivesLabel.Text = 'False Positives:';
            app.LabelFP = uilabel(app.ResultsPanel);
            app.LabelFP.Text = '0';
            app.FalseNegativesLabel = uilabel(app.ResultsPanel);
            app.FalseNegativesLabel.Text = 'False Negatives:';
            app.LabelFN = uilabel(app.ResultsPanel);
            app.LabelFN.Text = '0';
            app.TruePositivesLabel_2 = uilabel(app.ResultsPanel);
            app.TruePositivesLabel_2.Text = 'True Positives:';
            app.LabelTP2 = uilabel(app.ResultsPanel);
            app.LabelTP2.Text = '0';
            app.TrueNegativeLabel_2 = uilabel(app.ResultsPanel);
            app.TrueNegativeLabel_2.Text = 'True Negative:';
            app.LabelTN2 = uilabel(app.ResultsPanel);
            app.LabelTN2.Text = '0';
            app.FalsePositivesLabel_2 = uilabel(app.ResultsPanel);
            app.FalsePositivesLabel_2.Text = 'False Positives:';
            app.LabelFP2 = uilabel(app.ResultsPanel);
            app.LabelFP2.Text = '0';
            app.FalseNegativesLabel_2 = uilabel(app.ResultsPanel);
            app.FalseNegativesLabel_2.Text = 'False Negatives:';
            app.LabelFN2 = uilabel(app.ResultsPanel);
            app.LabelFN2.Text = '0';
            app.AccuracyLabel = uilabel(app.ResultsPanel);
            app.AccuracyLabel.Text = 'Accuracy:';
            app.AccuracyDetection = uilabel(app.ResultsPanel);
            app.AccuracyDetection.Text = '0';
            app.AccuracyLabel_2 = uilabel(app.ResultsPanel);
            app.AccuracyLabel_2.Text = 'Accuracy:';
            app.AccuracyPrediction = uilabel(app.ResultsPanel);
            app.AccuracyPrediction.Text = '0';
            app.DatasetBuildLampLabel = uilabel(app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.DatasetBuildLampLabel.Text = 'Dataset Build';
            app.DatasetBuildLamp = uilamp(app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.NetworkTrainedLampLabel = uilabel(app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.NetworkTrainedLampLabel.Text = 'Network Trained';
            app.NetworkTrainedLamp = uilamp(app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.NetworkSavedLampLabel = uilabel(app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.NetworkSavedLampLabel.Text = 'Network Saved';
            app.NetworkSavedLamp = uilamp(app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.NetworkSavedLampLabel_2 = uilabel(app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.NetworkSavedLampLabel_2.Text = 'Network Tested';
            app.NetworkTestedLamp = uilamp(app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.TrainRecurrentNetworkPanel = uipanel(app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.TrainRecurrentNetworkPanel.Title = 'Train Recurrent Network';
            app.GoalDropDown_2Label = uilabel(app.TrainRecurrentNetworkPanel);
            app.GoalDropDown_2Label.Text = 'Goal';
            app.GoalDropDown_Rec = uidropdown(app.TrainRecurrentNetworkPanel);
            app.GoalDropDown_Rec.Items = {'Prediction', 'Detection'};
            app.GoalDropDown_Rec.Value = 'Prediction';
            app.TrainingDropDown_2Label = uilabel(app.TrainRecurrentNetworkPanel);
            app.TrainingDropDown_2Label.Text = 'Training %';
            app.TrainingDropDown_Rec = uidropdown(app.TrainRecurrentNetworkPanel);
            app.TrainingDropDown_Rec.Items = {'0.7', '0.8'};
            app.TrainingDropDown_Rec.Value = '0.7';
            app.NeuronsperLayersDropDown_2Label = uilabel(app.TrainRecurrentNetworkPanel);
            app.NeuronsperLayersDropDown_2Label.Text = 'Neurons per Layers';
            app.NeuronsperLayersDropDown_Rec = uidropdown(app.TrainRecurrentNetworkPanel);
            app.NeuronsperLayersDropDown_Rec.Items = {'10', '20', '40', '120'};
            app.NeuronsperLayersDropDown_Rec.Value = '20';
            app.TrainRecButton = uibutton(app.TrainRecurrentNetworkPanel, 'push');
            app.TrainRecButton.ButtonPushedFcn = createCallbackFcn(app, @TrainRecButtonPushed, true);
            app.TrainRecButton.Text = 'Train';
            app.SpecializationErrorDropDown_2Label = uilabel(app.TrainRecurrentNetworkPanel);
            app.SpecializationErrorDropDown_2Label.Text = 'Specialization (Error)';
            app.SpecializationErrorDropDown_Rec = uidropdown(app.TrainRecurrentNetworkPanel);
            app.SpecializationErrorDropDown_Rec.Items = {'High', 'Medium', 'None'};
            app.SpecializationErrorDropDown_Rec.Value = 'High';
            app.NumberOfFeaturesDropDownLabel_Rec = uilabel(app.TrainRecurrentNetworkPanel);
            app.NumberOfFeaturesDropDownLabel_Rec.Text = 'Number of Features';
            app.NumberOfFeaturesDropDown_Rec = uidropdown(app.TrainRecurrentNetworkPanel);
            app.NumberOfFeaturesDropDown_Rec.Items = {'29', '25', '20', '10'};
            app.NumberOfFeaturesDropDown_Rec.Value = '29';
            app.ClassificationTypeDropDownLabel_Rec = uilabel(app.TrainRecurrentNetworkPanel);
            app.ClassificationTypeDropDownLabel_Rec.Text = 'Classification Type';
            app.ClassificationTypeDropDown_Rec = uidropdown(app.TrainRecurrentNetworkPanel);
            app.ClassificationTypeDropDown_Rec.Items = {'Single', 'Group'};
            app.ClassificationTypeDropDown_Rec.Value = 'Single';
            app.NoClassBalancingTimeSeriesLabel = uilabel(app.TrainRecurrentNetworkPanel);
            app.NoClassBalancingTimeSeriesLabel.Text = 'No Class Balancing (Time-Series)';
            app.TrainDeepLearningPanel = uipanel(app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.TrainDeepLearningPanel.Title = 'Train Deep Learning';
            app.NetworkTypeDropDown_3Label = uilabel(app.TrainDeepLearningPanel);
            app.NetworkTypeDropDown_3Label.Text = 'Network Type';
            app.DeepNetworkType = uidropdown(app.TrainDeepLearningPanel);
            app.DeepNetworkType.Items = {'CNN', 'LSTM'};
            app.DeepNetworkType.Value = 'LSTM';
            app.TrainButton = uibutton(app.TrainDeepLearningPanel, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.Clusteringwith2and3featuresencodedPanel = uipanel(app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.Clusteringwith2and3featuresencodedPanel.Title = 'Clustering with 2 and 3 features encoded ';
            app.Plot2dButton = uibutton(app.Clusteringwith2and3featuresencodedPanel, 'push');
            app.Plot2dButton.ButtonPushedFcn = createCallbackFcn(app, @Plot2dButtonPushed, true);
            app.Plot2dButton.Text = 'Plot 2d';
            app.Plot3dButton = uibutton(app.Clusteringwith2and3featuresencodedPanel, 'push');
            app.Plot3dButton.ButtonPushedFcn = createCallbackFcn(app, @Plot3dButtonPushed, true);
            app.Plot3dButton.Text = 'Plot 3d';
            app.ClustersLabel = uilabel(app.Clusteringwith2and3featuresencodedPanel);
            app.ClustersLabel.Text = 'Clusters:';
            app.ClustersDropDown = uidropdown(app.Clusteringwith2and3featuresencodedPanel);
            app.ClustersDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.ClustersDropDown.Value = '4';
            % Show the figure after all components are created
            app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_assignment_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure)
        end
    end
end